classdef WEB_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CatlogodePlantasMenu        matlab.ui.container.Menu
        ConfiguracindeSensoresMenu  matlab.ui.container.Menu
        MonitoreoMenu               matlab.ui.container.Menu
        AgregarButton               matlab.ui.control.Button
        EliminarButton              matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        CalibrarButton              matlab.ui.control.Button
        DetenerButton               matlab.ui.control.Button
        SensornocalibradoLampLabel  matlab.ui.control.Label
        SensornocalibradoLamp       matlab.ui.control.Lamp
        PresininicialmbarEditFieldLabel  matlab.ui.control.Label
        PresininicialmbarEditField  matlab.ui.control.NumericEditField
        MrgendeErrorSliderLabel     matlab.ui.control.Label
        MrgendeErrorSlider          matlab.ui.control.Slider
    end

    properties (Access = public)
         socket
         t
         AgregarApp              % Dialog box app
         idPlanta
         Nombre
         Sensor
         Presion
         Habilitado
         Descripcion
         
         selected
    end
    
    methods (Access = public)
        
        function sendData(app,data)
            fwrite(app.socket, data)
        end
        
        function data = receiveData(app)
            data = fgetl(app.socket);
        end
        
        function updateTable(app)
            app.sendData('getPlants')
            tabla = app.receiveData();
            tabla = replace(tabla,"u'","");
            tabla = replace(tabla,"'","");
            tabla = replace(tabla,"None"," ");
            tabla = replace(tabla,"[(","");
            tabla = replace(tabla,")]","");
            tabla = split(tabla,'), (');
            tabla = split(tabla,',');
            tabla = replace(tabla,"'","");
            app.idPlanta = tabla(:,1);
            app.idPlanta = cellfun(@str2num,app.idPlanta);
            app.Nombre = tabla(:,2);
            app.Sensor = tabla(:,3);
            app.Sensor = categorical(app.Sensor,{'1';'2'},"Protected",true);
            app.Presion = tabla(:,4);
            app.Presion = cellfun(@str2num,app.Presion);
            app.Habilitado = tabla(:,5);
            app.Habilitado = cellfun(@str2num,app.Habilitado);
            app.Habilitado = logical(app.Habilitado);
            app.Descripcion = tabla(:,6);
            tabla = table(app.Nombre,app.Sensor,app.Descripcion,app.Habilitado);
            app.UITable.Data = tabla;
        end
        
        function modifyPlant(app,indice,idPlanta,newData)
            if indice == 1
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Nombre',',',newData);
                data = char(data);
                app.sendData(data)
            elseif indice == 2
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Sensor',',',newData);
                data = char(data);
                app.sendData(data)
            elseif indice == 3
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Descripcion',',',newData);
                data = char(data);
                app.sendData(data)
            else 
                newData = num2str(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Habilitado',',',newData);
                data = char(data);
                app.sendData(data)
            end
            
        end
        
        function confirmClose(app, src, event)
            % Determine which dialog button the user clicked
            answer = event.SelectedOption;
            
            % Close the app if the user clicks OK
            if strcmp(answer,'OK')
                id = num2str(app.idPlanta(app.selected));
                app.deletePlant(id)
                app.receiveData()
                app.updateTable()
                app.EliminarButton.Enable = 'off';
            end
            app.EliminarButton.Enable = 'off';
        end
        
        function deletePlant(app,idPlanta)
             data = strcat('deletePlant',',',idPlanta);
             data = char(data);
             app.sendData(data)
        end
        
        function updateEnabledPlants(app)
            app.sendData('getEnabledPlants')
            tabla = app.receiveData();
            tabla = replace(tabla,"u'","");
            tabla = replace(tabla,"'","");
            tabla = replace(tabla,"None"," ");
            tabla = replace(tabla,"[(","");
            tabla = replace(tabla,")]","");
            tabla = split(tabla,'), (');
            tabla = split(tabla,',');
            tabla = replace(tabla,"'","");
            [m,n] = size(tabla);
            if n == 1
                tabla = tabla';
            end
            app.idPlanta = tabla(:,1);
            app.idPlanta = cellfun(@str2num,app.idPlanta);
            app.Nombre = tabla(:,2);
            app.Sensor = tabla(:,3);
            app.Sensor = categorical(app.Sensor,{'1';'2'},"Protected",true);
            app.Presion = tabla(:,4);
            app.Presion = cellfun(@str2num,app.Presion);
            tabla = table(app.Nombre,app.Sensor,app.Presion);
            app.UITable.Data = tabla;
        end
        
        function out = selectedPlants(app)
            count1 = 0;
            count2 = 0;            
            [m,n] = size(app.Sensor);
            if m > 2
                out = 0;
            else
                for i = 1 : m
                    if app.Sensor(i,1) == categorical(1)
                        count1 = count1 + 1;
                    elseif app.Sensor(i,1) == categorical(2)
                        count2 = count2 + 1;
                    end
                end
                if count1 > 1 || count2 > 1
                    out = 0;
                else
                    out = 1;
                end
            end
        end
        
        function calibratePlant(app,idPlanta)
            data = strcat('calibratePlant',',',idPlanta);
            data = char(data);
            app.sendData(data)
        end


        function test(app)
            'alan'
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            close all
            
            app.t =timer('TimerFcn',@(~,~)app.test,'StartDelay',1);
%             app.t.StartDelay = 1;
%             app.t.TimerFcn = @(~,~)app.test;
            start(app.t) 
            
            
            app.EliminarButton.Enable = 'off';
            app.socket = tcpip('192.168.7.140', 30000, 'NetworkRole', 'client');
            fopen(app.socket)
            
            app.updateTable()
            
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            
            app.CatlogodePlantasMenu.Enable = 'off';
            app.AgregarButton.Enable = 'off';
            app.EliminarButton.Enable = 'off';
            app.UITable.Enable = 'off';
            app.ConfiguracindeSensoresMenu.Enable = 'off';
            app.MonitoreoMenu.Enable = 'off';
            
            app.AgregarApp = agregar(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.socket)
            stop(app.t)
            delete(app.t)
            delete(app)
            
        end

        % Callback function
        function ModificarButtonPushed(app, event)
                 
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            id = num2str(app.idPlanta(indices(1)));
            app.modifyPlant(indices(2),id,newData)
            app.receiveData()
            app.updateTable()
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.EliminarButton.Enable = 'on';
            app.CalibrarButton.Enable = 'on';
            indices = event.Indices;
            app.selected = indices(1);
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            plant = app.Nombre(app.selected);
            msg = strcat('¿Estas seguro de eliminar ',plant,'?');
            uiconfirm(app.UIFigure,msg,'Eliminar Planta','CloseFcn',@app.confirmClose);
            
        end

        % Menu selected function: ConfiguracindeSensoresMenu
        function ConfiguracindeSensoresMenuSelected(app, event)
            app.AgregarButton.Visible = 'off';
            app.EliminarButton.Visible = 'off';
            app.CalibrarButton.Visible = 'on';
            app.CalibrarButton.Enable = 'off';
            app.SensornocalibradoLamp.Visible = 'on';
            app.SensornocalibradoLamp.Enable = 'off';
            app.PresininicialmbarEditField.Visible = 'on';
            app.PresininicialmbarEditFieldLabel.Visible = 'on';
            app.MrgendeErrorSlider.Visible = 'on';
            app.MrgendeErrorSliderLabel.Visible = 'on';
            app.UITable.ColumnEditable = false;
            app.UITable.ColumnName = {'Nombre','Sensor','Presión'};
            app.updateEnabledPlants()
            
        end

        % Menu selected function: CatlogodePlantasMenu
        function CatlogodePlantasMenuSelected(app, event)
            app.AgregarButton.Visible = 'on';
            app.EliminarButton.Visible = 'on';
            app.EliminarButton.Enable = 'off';
            app.CalibrarButton.Visible = 'off';
            app.SensornocalibradoLamp.Visible = 'off';
            app.PresininicialmbarEditField.Visible = 'off';
            app.PresininicialmbarEditFieldLabel.Visible = 'off';
            app.MrgendeErrorSlider.Visible = 'off';
            app.MrgendeErrorSliderLabel.Visible = 'off';
            app.UITable.ColumnEditable = true;
            app.UITable.ColumnName = {'Nombre','Sensor','Descripción','Habilitar'};
            app.updateTable()
            
        end

        % Button pushed function: CalibrarButton
        function CalibrarButtonPushed(app, event)
            if app.selectedPlants() == 0
                uialert(app.UIFigure,'¡Habilitar una planta por cada sensor!','Precaución','Icon','warning',"CloseFcn",'uiresume(gcbf)') 
                uiwait(gcbf)
            else
                app.CalibrarButton.Visible = 'off';
                app.DetenerButton.Visible = 'on';
                app.calibratePlant(num2str(app.idPlanta(app.selected)))
            end
            
        end

        % Value changed function: PresininicialmbarEditField
        function PresininicialmbarEditFieldValueChanged(app, event)
            value = app.PresininicialmbarEditField.Value;
            value = char(value);
            data = strcat('setSetting',',','Presión correcta',',',value);
            data = char(data);
            app.sendData(data)
            app.receiveData()
        end

        % Value changed function: MrgendeErrorSlider
        function MrgendeErrorSliderValueChanged(app, event)
            value = app.MrgendeErrorSlider.Value;
            value = char(value);
            data = strcat('setSetting',',','Pmargen',',',value);
            data = char(data);
            app.sendData(data)
            app.receiveData()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CatlogodePlantasMenu = uimenu(app.UIFigure);
            app.CatlogodePlantasMenu.MenuSelectedFcn = createCallbackFcn(app, @CatlogodePlantasMenuSelected, true);
            app.CatlogodePlantasMenu.Text = 'Catálogo de Plantas';
            app.ConfiguracindeSensoresMenu = uimenu(app.UIFigure);
            app.ConfiguracindeSensoresMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfiguracindeSensoresMenuSelected, true);
            app.ConfiguracindeSensoresMenu.Text = 'Configuración de Sensores';
            app.MonitoreoMenu = uimenu(app.UIFigure);
            app.MonitoreoMenu.Text = 'Monitoreo';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Nombre'; 'Sensor'; 'Descripción'; 'Habilitar'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CalibrarButton = uibutton(app.UIFigure, 'push');
            app.CalibrarButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.CalibrarButton.Visible = 'off';
            app.CalibrarButton.Text = 'Calibrar';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.Visible = 'off';
            app.DetenerButton.Text = 'Detener';
            app.SensornocalibradoLampLabel = uilabel(app.UIFigure);
            app.SensornocalibradoLampLabel.Visible = 'off';
            app.SensornocalibradoLampLabel.Text = 'Sensor no calibrado';
            app.SensornocalibradoLamp = uilamp(app.UIFigure);
            app.SensornocalibradoLamp.Visible = 'off';
            app.PresininicialmbarEditFieldLabel = uilabel(app.UIFigure);
            app.PresininicialmbarEditFieldLabel.Visible = 'off';
            app.PresininicialmbarEditFieldLabel.Text = 'Presión inicial (mbar)';
            app.PresininicialmbarEditField = uieditfield(app.UIFigure, 'numeric');
            app.PresininicialmbarEditField.Limits = [1000 10000];
            app.PresininicialmbarEditField.ValueChangedFcn = createCallbackFcn(app, @PresininicialmbarEditFieldValueChanged, true);
            app.PresininicialmbarEditField.Visible = 'off';
            app.PresininicialmbarEditField.Value = 1000;
            app.MrgendeErrorSliderLabel = uilabel(app.UIFigure);
            app.MrgendeErrorSliderLabel.Visible = 'off';
            app.MrgendeErrorSliderLabel.Text = 'Márgen de  Error (%)';
            app.MrgendeErrorSlider = uislider(app.UIFigure);
            app.MrgendeErrorSlider.Limits = [5 50];
            app.MrgendeErrorSlider.MajorTicks = [5 15 25 35 45 50];
            app.MrgendeErrorSlider.MajorTickLabels = {'5', '15', '25', '35', '45', '50'};
            app.MrgendeErrorSlider.ValueChangedFcn = createCallbackFcn(app, @MrgendeErrorSliderValueChanged, true);
            app.MrgendeErrorSlider.MinorTicks = [5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.MrgendeErrorSlider.Visible = 'off';
            app.MrgendeErrorSlider.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WEB_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end