classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        CatlogodeplantasTab         matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        AgregarButton               matlab.ui.control.Button
        EliminarButton              matlab.ui.control.Button
        ConfiguracindesensoresTab   matlab.ui.container.Tab
        UITable2                    matlab.ui.control.Table
        PresininicialmbarEditFieldLabel  matlab.ui.control.Label
        PresininicialmbarEditField  matlab.ui.control.NumericEditField
        MrgenSliderLabel            matlab.ui.control.Label
        MrgenSlider                 matlab.ui.control.Slider
        CalibrarButton              matlab.ui.control.StateButton
        MonitoreoTab                matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        InicioButton                matlab.ui.control.StateButton
        ExportarTab                 matlab.ui.container.Tab
        UITable3                    matlab.ui.control.Table
        MostrardesdeLabel           matlab.ui.control.Label
        MostrardesdeDatePicker      matlab.ui.control.DatePicker
        hastaDatePickerLabel        matlab.ui.control.Label
        hastaDatePicker             matlab.ui.control.DatePicker
        ExportarButton              matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        negocio
        tabla
        AgregarApp              % Dialog box app
        Id
        Nombre
        indices
        
    end
    
    methods (Access = public) 
        
        function updateMainTable(app)
            app.tabla = app.negocio.getTable();
            app.Id = app.tabla(:,1);
            app.Nombre = app.tabla(:,2);
            app.UITable.Data = app.tabla(:,2:4);
        end
        
        function newPlant(app,data)
            msg = app.negocio.addPlant(data);
            app.updateMainTable()
        end
        
        function modifyPlant(app,indices,newData)
            msg = app.negocio.modifyPlant(app.Id,indices,newData);
            app.updateMainTable()            
        end
        function deletePlant(app, src, event)          
            % Determine which dialog button the user clicked
            answer = event.SelectedOption;
            
            % Close the app if the user clicks OK
            if strcmp(answer,'OK')
                app.negocio.deletePlant(app.Id,app.indices)
                app.EliminarButton.Enable = 'off';
            else
                app.EliminarButton.Enable = 'off';
            end    
            app.EliminarButton.Enable = 'off'; 
            app.updateMainTable()    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            close all
            
%             app.t =timer;
%             app.t.TimerFcn = @(~, thisEvent)app.callEnabledPlants;
%             app.t.Period = 1;
%             app.t.ExecutionMode = 'fixedSpacing';
            app.EliminarButton.Enable = 'off';
            app.negocio = negocio();
            app.updateMainTable()
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            app.AgregarApp = agregar(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.indices = event.Indices;
            app.EliminarButton.Enable = 'on';
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.modifyPlant(indices,newData)
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            nombre = table2array(app.Nombre);
            plant = nombre(app.indices(1));
            msg = strcat('¿Estas seguro de eliminar',' ',plant,'?');
            uiconfirm(app.UIFigure,msg,'Eliminar Planta','CloseFcn',@app.deletePlant);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CatlogodeplantasTab = uitab(app.TabGroup);
            app.CatlogodeplantasTab.Title = 'Catálogo de plantas';
            app.UITable = uitable(app.CatlogodeplantasTab);
            app.UITable.ColumnName = {'Nombre'; 'Sensor'; 'Descripción'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AgregarButton = uibutton(app.CatlogodeplantasTab, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.CatlogodeplantasTab, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.ConfiguracindesensoresTab = uitab(app.TabGroup);
            app.ConfiguracindesensoresTab.Title = 'Configuración de sensores';
            app.UITable2 = uitable(app.ConfiguracindesensoresTab);
            app.UITable2.ColumnName = {'Nombre'; 'Sensor'; 'Descripción'; 'Habilitar'; 'Calibrado'};
            app.UITable2.RowName = {};
            app.PresininicialmbarEditFieldLabel = uilabel(app.ConfiguracindesensoresTab);
            app.PresininicialmbarEditFieldLabel.Text = 'Presión inicial (mbar)';
            app.PresininicialmbarEditField = uieditfield(app.ConfiguracindesensoresTab, 'numeric');
            app.PresininicialmbarEditField.Value = 1000;
            app.MrgenSliderLabel = uilabel(app.ConfiguracindesensoresTab);
            app.MrgenSliderLabel.Text = 'Márgen';
            app.MrgenSlider = uislider(app.ConfiguracindesensoresTab);
            app.MrgenSlider.Limits = [10 100];
            app.MrgenSlider.Value = 10;
            app.CalibrarButton = uibutton(app.ConfiguracindesensoresTab, 'state');
            app.CalibrarButton.Text = 'Calibrar';
            app.MonitoreoTab = uitab(app.TabGroup);
            app.MonitoreoTab.Title = 'Monitoreo';
            app.UIAxes = uiaxes(app.MonitoreoTab);
            title(app.UIAxes, 'Monitoreo de plantas')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Presión')
            app.InicioButton = uibutton(app.MonitoreoTab, 'state');
            app.InicioButton.Text = 'Inicio';
            app.ExportarTab = uitab(app.TabGroup);
            app.ExportarTab.Title = 'Exportar';
            app.UITable3 = uitable(app.ExportarTab);
            app.UITable3.ColumnName = {'Nombre'; 'Seleccionar'};
            app.UITable3.RowName = {};
            app.MostrardesdeLabel = uilabel(app.ExportarTab);
            app.MostrardesdeLabel.Text = 'Mostrar desde:';
            app.MostrardesdeDatePicker = uidatepicker(app.ExportarTab);
            app.hastaDatePickerLabel = uilabel(app.ExportarTab);
            app.hastaDatePickerLabel.Text = 'hasta:';
            app.hastaDatePicker = uidatepicker(app.ExportarTab);
            app.ExportarButton = uibutton(app.ExportarTab, 'push');
            app.ExportarButton.Text = 'Exportar';
            app.UIAxes2 = uiaxes(app.ExportarTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end