classdef calibrar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PresinGaugeLabel            matlab.ui.control.Label
        PresinGauge                 matlab.ui.control.Gauge
        StatusLampLabel             matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        MrgendeError10SliderLabel   matlab.ui.control.Label
        MrgendeError10Slider        matlab.ui.control.Slider
        PresinInicialmbarEditFieldLabel  matlab.ui.control.Label
        PresinInicialmbarEditField  matlab.ui.control.NumericEditField
        mbarLabel                   matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        IniciarButton               matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        CallingApp % Description
        id
        nombre
        indice
    end
    
    methods (Access = public)
        
        function changeGauge(app)
            presion = str2double(app.CallingApp.getSetting('getSetting#presionInicial'));
            margen = str2double(app.CallingApp.getSetting('getSetting#Margen'));
            presion = str2double(presion);
            margen = str2double(margen);
            
            max = 2 * presion;
            inferior = presion - ((margen/100) * presion);
            superior = presion + ((margen/100) * presion);
            app.PresinGauge.Limits = [0  max];
            app.PresinGauge.ScaleColorLimits = [inferior superior; superior max;0 inferior];
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.CallingApp.UIFigure.Visible = 'off';
            app.indice = app.CallingApp.indicesConfigTable(1);
            app.id =  table2array(app.CallingApp.Id);
            app.id = app.id(app.indice);
            app.nombre = string(table2array(app.CallingApp.Nombre));
            app.nombre = app.nombre(app.indice);
                    
            app.Label.Text = app.nombre;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.UIFigure.Visible = 'on';
            app.CallingApp.modifySetting('setSetting#presionInicial#1000');
            app.CallingApp.modifySetting('setSetting#Margen#10');
            app.CallingApp.CalibrarButton.Enable  = 'off';
            % Delete the dialog box 
            delete(app)
            
        end

        % Value changed function: PresinInicialmbarEditField
        function PresinInicialmbarEditFieldValueChanged(app, event)
            value = app.PresinInicialmbarEditField.Value;
            data = strcat('setSetting','#','presionInicial','#',num2str(value));
            app.CallingApp.modifySetting(data)
            app.changeGauge()
            
        end

        % Value changed function: MrgendeError10Slider
        function MrgendeError10SliderValueChanged(app, event)
            value = app.MrgendeError10Slider.Value;
            data = strcat('setSetting','#','Margen','#',num2str(value));
            app.CallingApp.modifySetting(data)
            app.changeGauge()
        end

        % Value changing function: MrgendeError10Slider
        function MrgendeError10SliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = num2str(changingValue);
            msg =strcat('Márgen de Error (',changingValue,'%)');
            app.MrgendeError10SliderLabel.Text = msg;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PresinGaugeLabel = uilabel(app.UIFigure);
            app.PresinGaugeLabel.Text = 'Presión';
            app.PresinGauge = uigauge(app.UIFigure, 'circular');
            app.PresinGauge.Limits = [0 2000];
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.StatusLamp.Tooltip = {''};
            app.MrgendeError10SliderLabel = uilabel(app.UIFigure);
            app.MrgendeError10SliderLabel.Text = 'Márgen de  Error (10%)';
            app.MrgendeError10Slider = uislider(app.UIFigure);
            app.MrgendeError10Slider.Limits = [10 90];
            app.MrgendeError10Slider.ValueChangedFcn = createCallbackFcn(app, @MrgendeError10SliderValueChanged, true);
            app.MrgendeError10Slider.ValueChangingFcn = createCallbackFcn(app, @MrgendeError10SliderValueChanging, true);
            app.MrgendeError10Slider.MinorTicks = [10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.MrgendeError10Slider.Value = 10;
            app.PresinInicialmbarEditFieldLabel = uilabel(app.UIFigure);
            app.PresinInicialmbarEditFieldLabel.Text = 'Presión Inicial (mbar)';
            app.PresinInicialmbarEditField = uieditfield(app.UIFigure, 'numeric');
            app.PresinInicialmbarEditField.ValueChangedFcn = createCallbackFcn(app, @PresinInicialmbarEditFieldValueChanged, true);
            app.PresinInicialmbarEditField.Value = 1000;
            app.mbarLabel = uilabel(app.UIFigure);
            app.mbarLabel.Text = '0 mbar';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IniciarButton = uibutton(app.UIFigure, 'state');
            app.IniciarButton.Text = 'Iniciar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibrar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end